classdef LegsViewer3DFrom2DSlices < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FilterGroup       matlab.ui.container.ButtonGroup
        ToutButton        matlab.ui.control.RadioButton
        OsButton          matlab.ui.control.RadioButton
        MusclesButton     matlab.ui.control.RadioButton
        PeauButton        matlab.ui.control.RadioButton
        LegStateButton    matlab.ui.control.StateButton
        MinSliderLabel    matlab.ui.control.Label
        MinSlider         matlab.ui.control.Slider
        MaxSliderLabel    matlab.ui.control.Label
        MaxSlider         matlab.ui.control.Slider
        SliceSliderLabel  matlab.ui.control.Label
        SliceSlider       matlab.ui.control.Slider
        UIAxes3DImage     matlab.ui.control.UIAxes
        UIAxesSlice       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Début : Constantes
        CHEMIN_DOSSIER_JAMBE_REPOS = './Jambes/Repos'
        CHEMIN_DOSSIER_JAMBE_CONTRACTEE = './Jambes/Contractee'
        
        LONGUEUR_IMAGES = 513;
        LARGEUR_IMAGES = 513;
        
        ES_DILATATION = strel('disk',4);
        IMAGE_TEXTE = rgb2gray(imread('./Jambes/im_txt.png'));
        
        LEG_STATE_BUTTON_LABEL_0 = "Jambe au repos";
        LEG_STATE_BUTTON_LABEL_1 = "Jambe contractée";
        
        THRESHOLD_BONES = 200; % = Exclus.
        THRESHOLD_INF_MUSCLES = 60; % = Exclus.
        THRESHOLD_SUP_MUSCLES = 200; % = Exclus.
        
        FILTRE_AUCUN = 0;
        FILTRE_OS = 1;
        FILTRE_MUSCLES = 2;
        FILTRE_PEAU = 3;
        % Fin : Constantes
        
        % Début : Variables
        noms_images_jambe_repos; % Utile pour l'affichage d'un slice.
        noms_images_jambe_contractee;
        
        array3d_jambe_contractee_preprocessed;
        array3d_jambe_repos_preprocessed;
        
        leg_state = false; % false = repos, true = contracté
        filtre_choisi;
        
        jambe_repos_start = 1;
        jambe_repos_end = 1;
        jambe_contractee_start = 1;
        jambe_contractee_end = 1;
        % Fin : Variables
    end
    
    methods (Access = private)
        
        function readLegsImages(app)
            app.array3d_jambe_repos_preprocessed = zeros(app.LONGUEUR_IMAGES, app.LARGEUR_IMAGES, size(app.noms_images_jambe_repos,2));
            app.array3d_jambe_contractee_preprocessed = zeros(app.LONGUEUR_IMAGES, app.LARGEUR_IMAGES, size(app.noms_images_jambe_contractee,2));
            % Contrairement aux noms les arrays comme array3d_jambe_contractee_preprocessed ne sont pas encore prétraitées.
            for coupe = 1 : size(app.noms_images_jambe_repos,2)
                app.array3d_jambe_repos_preprocessed(:,:,coupe) = rgb2gray(imread(app.CHEMIN_DOSSIER_JAMBE_REPOS + "/" + app.noms_images_jambe_repos{coupe}));
            end
            
            for coupe = 1 : size(app.noms_images_jambe_contractee,2)
                app.array3d_jambe_contractee_preprocessed(:,:,coupe) = rgb2gray(imread(app.CHEMIN_DOSSIER_JAMBE_CONTRACTEE + "/" + app.noms_images_jambe_contractee{coupe}));
            end
        end
        
        function display2DSlicesInArrayOnUIAxes(app)
            cla(app.UIAxes3DImage);
            if(app.leg_state)
                if(app.filtre_choisi == app.FILTRE_AUCUN)
                    array3D_to_display = app.array3d_jambe_contractee_preprocessed(:,:,app.MinSlider.Value:app.MaxSlider.Value);
                end
                if(app.filtre_choisi == app.FILTRE_OS)
                    array3D_to_display = os(app.array3d_jambe_contractee_preprocessed(:,:,app.MinSlider.Value:app.MaxSlider.Value), app.THRESHOLD_BONES);
                end
                if(app.filtre_choisi == app.FILTRE_MUSCLES)
                    array3D_to_display = muscles(app.array3d_jambe_contractee_preprocessed(:,:,app.MinSlider.Value:app.MaxSlider.Value), app.THRESHOLD_INF_MUSCLES, app.THRESHOLD_SUP_MUSCLES);
                end
            else
                if(app.filtre_choisi == app.FILTRE_AUCUN)
                    array3D_to_display = app.array3d_jambe_repos_preprocessed(:,:,app.MinSlider.Value:app.MaxSlider.Value);
                end
                if(app.filtre_choisi == app.FILTRE_OS)
                    array3D_to_display = os(app.array3d_jambe_repos_preprocessed(:,:,app.MinSlider.Value:app.MaxSlider.Value), app.THRESHOLD_BONES);
                end
                if(app.filtre_choisi == app.FILTRE_MUSCLES)
                    array3D_to_display = muscles(app.array3d_jambe_repos_preprocessed(:,:,app.MinSlider.Value:app.MaxSlider.Value), app.THRESHOLD_INF_MUSCLES, app.THRESHOLD_SUP_MUSCLES);
                end
            end
            
            TSs = smooth3(array3D_to_display, 'box', 1);     
            % Construction avec isosurface
            patch(app.UIAxes3DImage, isosurface(TSs,0),'FaceColor', 'red','EdgeColor','none');
            patch(app.UIAxes3DImage, isocaps(TSs,1),'FaceColor','interp','EdgeColor','none');
            %isonormals(TSs,p);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.noms_images_jambe_repos,app.noms_images_jambe_contractee] = getLegsFileNames(app.CHEMIN_DOSSIER_JAMBE_REPOS,app.CHEMIN_DOSSIER_JAMBE_CONTRACTEE); % On extrait les noms de fichiers des images.
            readLegsImages(app); % On lit toutes les images.
            
            [app.array3d_jambe_repos_preprocessed] = preProcess3DArray(app.array3d_jambe_repos_preprocessed, app.IMAGE_TEXTE, app.ES_DILATATION);
            [app.array3d_jambe_contractee_preprocessed] = preProcess3DArray(app.array3d_jambe_contractee_preprocessed, app.IMAGE_TEXTE, app.ES_DILATATION);
            
            app.jambe_repos_end = size(app.array3d_jambe_repos_preprocessed,3); % Nécessaire pour les sliders.
            app.jambe_contractee_end = size(app.array3d_jambe_contractee_preprocessed,3);
            
            app.MinSlider.Limits = [1 (app.jambe_repos_end - 1)];
            app.MaxSlider.Limits = [1 app.jambe_repos_end];
            app.MinSlider.Value = 1;
            app.MaxSlider.Value = app.jambe_repos_end;
            app.SliceSlider.Limits = [1 app.jambe_repos_end];
            app.SliceSlider.Value = 1;
            imshow(imread(app.CHEMIN_DOSSIER_JAMBE_REPOS + "/" + app.noms_images_jambe_repos{app.SliceSlider.Value}),'Parent', app.UIAxesSlice);
            
            app.filtre_choisi = app.FILTRE_AUCUN; % On affiche tout (muscles, os, peau...)
            display2DSlicesInArrayOnUIAxes(app);
        end

        % Value changed function: LegStateButton
        function LegStateButtonValueChanged(app, event)
            state = app.LegStateButton.Value;
            app.leg_state = state;
            if(app.leg_state)
                app.LegStateButton.Text = app.LEG_STATE_BUTTON_LABEL_1;
                app.MinSlider.Limits = [1 (app.jambe_contractee_end - 1)];
                app.MaxSlider.Limits = [1 app.jambe_contractee_end];
                app.SliceSlider.Limits = [1 app.jambe_contractee_end];
                imshow(imread(app.CHEMIN_DOSSIER_JAMBE_CONTRACTEE + "/" + app.noms_images_jambe_contractee{app.SliceSlider.Value}),'Parent', app.UIAxesSlice);
            else
                app.LegStateButton.Text = app.LEG_STATE_BUTTON_LABEL_0;
                app.MinSlider.Limits = [1 (app.jambe_repos_end - 1)];
                app.MaxSlider.Limits = [1 app.jambe_repos_end];
                app.SliceSlider.Limits = [1 app.jambe_repos_end];
                imshow(imread(app.CHEMIN_DOSSIER_JAMBE_REPOS + "/" + app.noms_images_jambe_repos{app.SliceSlider.Value}),'Parent', app.UIAxesSlice);
            end
            display2DSlicesInArrayOnUIAxes(app)
        end

        % Selection changed function: FilterGroup
        function FilterGroupSelectionChanged(app, event)
            selectedButton = app.FilterGroup.SelectedObject;
            if(selectedButton == app.ToutButton)
                app.filtre_choisi = app.FILTRE_AUCUN;
            end
            
            if (selectedButton == app.OsButton)
                app.filtre_choisi = app.FILTRE_OS;
            end
            
            if (selectedButton == app.MusclesButton)
                app.filtre_choisi = app.FILTRE_MUSCLES;
            end
            
            if (selectedButton == app.PeauButton)
                app.filtre_choisi = app.FILTRE_PEAU;
            end
            display2DSlicesInArrayOnUIAxes(app)
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            app.MinSlider.Value = round(app.MinSlider.Value);
            if(app.MaxSlider.Value < app.MinSlider.Value)
                app.MaxSlider.Value = app.MinSlider.Value + 1;
            end
            if(app.leg_state)
                app.MaxSlider.Limits = [app.MinSlider.Value app.jambe_contractee_end];
            else
                app.MaxSlider.Limits = [app.MinSlider.Value app.jambe_repos_end];
            end
            display2DSlicesInArrayOnUIAxes(app);
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            app.MaxSlider.Value = round(app.MaxSlider.Value);
            if(app.MaxSlider.Value == app.MinSlider.Value)
                app.MaxSlider.Value = app.MaxSlider.Value + 1;
            end
            display2DSlicesInArrayOnUIAxes(app);
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.SliceSlider.Value = round(app.SliceSlider.Value);
            if(app.leg_state)
                imshow(imread(app.CHEMIN_DOSSIER_JAMBE_CONTRACTEE + "/" + app.noms_images_jambe_contractee{app.SliceSlider.Value}),'Parent', app.UIAxesSlice);
            else
                imshow(imread(app.CHEMIN_DOSSIER_JAMBE_REPOS + "/" + app.noms_images_jambe_repos{app.SliceSlider.Value}),'Parent', app.UIAxesSlice);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilterGroup = uibuttongroup(app.UIFigure);
            app.FilterGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterGroupSelectionChanged, true);
            app.FilterGroup.Title = 'Filtres';
            app.ToutButton = uiradiobutton(app.FilterGroup);
            app.ToutButton.Text = 'Tout';
            app.ToutButton.Value = true;
            app.OsButton = uiradiobutton(app.FilterGroup);
            app.OsButton.Text = 'Os';
            app.MusclesButton = uiradiobutton(app.FilterGroup);
            app.MusclesButton.Text = {'Muscles'; ''};
            app.PeauButton = uiradiobutton(app.FilterGroup);
            app.PeauButton.Text = 'Peau';
            app.LegStateButton = uibutton(app.UIFigure, 'state');
            app.LegStateButton.ValueChangedFcn = createCallbackFcn(app, @LegStateButtonValueChanged, true);
            app.LegStateButton.Text = 'Jambe au repos';
            app.MinSliderLabel = uilabel(app.UIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.UIFigure);
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MaxSliderLabel = uilabel(app.UIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.UIFigure);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.UIAxes3DImage = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3DImage, 'X')
            ylabel(app.UIAxes3DImage, 'Y')
            zlabel(app.UIAxes3DImage, 'Z')
            app.UIAxesSlice = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LegsViewer3DFrom2DSlices

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end